classdef CustomerUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Shower        matlab.ui.container.Menu
        ShowerGel     matlab.ui.container.Menu
        Shampoo       matlab.ui.container.Menu
        Menu          matlab.ui.container.Menu
        Menu2         matlab.ui.container.Menu
        Menu3         matlab.ui.container.Menu
        Menu4         matlab.ui.container.Menu
        Menu5         matlab.ui.container.Menu
        Food          matlab.ui.container.Menu
        Menu_2        matlab.ui.container.Menu
        Menu_8        matlab.ui.container.Menu
        Menu3_2       matlab.ui.container.Menu
        Menu4_2       matlab.ui.container.Menu
        Menu5_2       matlab.ui.container.Menu
        Menu6         matlab.ui.container.Menu
        Menu7         matlab.ui.container.Menu
        Menu8         matlab.ui.container.Menu
        Water         matlab.ui.container.Menu
        Menu_3        matlab.ui.container.Menu
        Menu2_3       matlab.ui.container.Menu
        Menu3_3       matlab.ui.container.Menu
        Menu4_3       matlab.ui.container.Menu
        Menu5_3       matlab.ui.container.Menu
        Menu6_2       matlab.ui.container.Menu
        Menu7_2       matlab.ui.container.Menu
        Menu8_2       matlab.ui.container.Menu
        Drink         matlab.ui.container.Menu
        Menu_4        matlab.ui.container.Menu
        Menu2_4       matlab.ui.container.Menu
        Menu3_4       matlab.ui.container.Menu
        Menu4_4       matlab.ui.container.Menu
        Menu5_4       matlab.ui.container.Menu
        Menu6_3       matlab.ui.container.Menu
        Menu7_3       matlab.ui.container.Menu
        Menu8_3       matlab.ui.container.Menu
        Tools         matlab.ui.container.Menu
        Menu_5        matlab.ui.container.Menu
        Menu2_5       matlab.ui.container.Menu
        Menu3_5       matlab.ui.container.Menu
        Menu4_5       matlab.ui.container.Menu
        Menu5_5       matlab.ui.container.Menu
        Menu6_4       matlab.ui.container.Menu
        Menu7_4       matlab.ui.container.Menu
        Menu8_4       matlab.ui.container.Menu
        Cloth         matlab.ui.container.Menu
        Menu_6        matlab.ui.container.Menu
        Menu2_6       matlab.ui.container.Menu
        Menu3_6       matlab.ui.container.Menu
        Menu4_6       matlab.ui.container.Menu
        Menu5_6       matlab.ui.container.Menu
        Menu6_5       matlab.ui.container.Menu
        Menu7_5       matlab.ui.container.Menu
        Menu8_5       matlab.ui.container.Menu
        Digits        matlab.ui.container.Menu
        Menu_7        matlab.ui.container.Menu
        Menu2_7       matlab.ui.container.Menu
        Menu3_7       matlab.ui.container.Menu
        Menu4_7       matlab.ui.container.Menu
        Menu5_7       matlab.ui.container.Menu
        Menu6_6       matlab.ui.container.Menu
        Menu7_6       matlab.ui.container.Menu
        Menu8_6       matlab.ui.container.Menu
        GridLayout    matlab.ui.container.GridLayout
        LeftPanel     matlab.ui.container.Panel
        item1         matlab.ui.control.Button
        item2         matlab.ui.control.Button
        item1_plus    matlab.ui.control.Button
        item2_plus    matlab.ui.control.Button
        item1_minus   matlab.ui.control.Button
        item2_minus   matlab.ui.control.Button
        item3         matlab.ui.control.Button
        item4         matlab.ui.control.Button
        item5         matlab.ui.control.Button
        item6         matlab.ui.control.Button
        item7         matlab.ui.control.Button
        item8         matlab.ui.control.Button
        item9         matlab.ui.control.Button
        item10        matlab.ui.control.Button
        item3_plus    matlab.ui.control.Button
        item3_minus   matlab.ui.control.Button
        item4_plus    matlab.ui.control.Button
        item4_minus   matlab.ui.control.Button
        item5_plus    matlab.ui.control.Button
        item5_minus   matlab.ui.control.Button
        item6_plus    matlab.ui.control.Button
        item6_minus   matlab.ui.control.Button
        item7_plus    matlab.ui.control.Button
        item7_minus   matlab.ui.control.Button
        item8_plus    matlab.ui.control.Button
        item8_minus   matlab.ui.control.Button
        item9_plus    matlab.ui.control.Button
        item9_minus   matlab.ui.control.Button
        item10_plus   matlab.ui.control.Button
        item10_minus  matlab.ui.control.Button
        CenterPanel   matlab.ui.container.Panel
        Image         matlab.ui.control.Image
        Label         matlab.ui.control.Label
        unitPrice     matlab.ui.control.NumericEditField
        PaySuccess    matlab.ui.control.Button
        RightPanel    matlab.ui.container.Panel
        UITable       matlab.ui.control.Table
        Label_2       matlab.ui.control.Label
        TotalPrice    matlab.ui.control.NumericEditField
        PayButton     matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        item_nums = 0;
        item_name = [""];
        item_number = [];
        item_price = [];
        
        max_items_per_type = 10
        
        cashier = Cashier();
        db = Database();
    end
    
    methods (Access = private)
        
        function change_order_add(app, item)
            % find goods' price 
            goods = app.db.findGoods(item.Text);
            if goods.brand == item.Text
                app.unitPrice.Value = goods.price;
            end
            
            
            is_find = false;
            %app.UITable.RowName = {'1';};
            % check if item is in the order list
            for i = 1:app.item_nums
                % find the same item in the order, increase number and
                % price
                if app.item_name(i,1) == item.Text
                    app.item_number(i,1) = app.item_number(i) + 1;
                    
                    app.item_price(i,1) = app.item_price(i) + app.unitPrice.Value;
                    is_find = true;
                end
            end
            
            if is_find == false
                %app.UITable.RowName{app.item_nums} = app.item_nums;
                if app.item_nums == 0
                    app.item_name = [""];
                end
                app.item_nums = app.item_nums + 1;
                app.item_name(app.item_nums,1) = item.Text;
                app.item_number(app.item_nums,1) = 1;
                app.item_price(app.item_nums,1) = app.unitPrice.Value;
                
            end
            % change total price
            total_price = sum(app.item_price);
            app.TotalPrice.Value = total_price;
            
            tdata = table(app.item_name, app.item_number, app.item_price);
            app.UITable.Data = tdata;
        end
        
        
        function change_order_sub(app,item)
            is_find = false;
            %app.UITable.RowName = {'1';};
            % check if item is in the order list
            
            for i = 1:app.item_nums
                % find the same item in the order, increase number and
                % price
                if app.item_name(i,1) == item.Text
                    app.item_number(i,1) = app.item_number(i) - 1;
                    app.item_price(i,1) = app.item_price(i) - app.unitPrice.Value;
                    is_find = true;
                    break;
                end
            end
            if is_find == true && app.item_number(i,1) == 0
                
                %app.UITable.RowName{i,:} = [];
                app.item_name(i,:) = [];
                app.item_number(i,:) = [];
                app.item_price(i,:) = [];
                app.item_nums = app.item_nums - 1;
            end
            % change total price
            total_price = sum(app.item_price);
            app.TotalPrice.Value = total_price;
            
            tdata = table(app.item_name, app.item_number, app.item_price);
            app.UITable.Data = tdata;
        end
        
        function itemButtonPushed(app, item)
            for i = 1:app.db.getGoodsNum()
                goods = app.db.getGoods(i);
                if goods.brand == item.Text
                    eval("app.Image.ImageSource = goods.img_src;");
                    eval("app.unitPrice.Value = goods.getPrice();")
                end
            end
            
            app.Label.Visible = 'on';
            app.unitPrice.Visible = 'on';
            app.Image.Visible = 'on';
        end
        
        function menuSelected(app, type_name)
            count = 1;
            for i = 1:app.db.db_goods_count
                goods = app.db.getGoods(i);
                if goods.type == type_name
                    eval("app.item"+num2str(count)+".Text = goods.getBrand(); ");
                    eval("app.item"+num2str(count)+".Visible = 'on'; ");
                    eval("app.item"+num2str(count)+"_plus.Visible = 'on'; ");
                    eval("app.item"+num2str(count)+"_minus.Visible = 'on'; ");
                    count = count + 1;
                end
            end
            for j = count : app.max_items_per_type
                eval("app.item"+num2str(j)+".Visible = 'off'; ");
                eval("app.item"+num2str(j)+"_plus.Visible = 'off'; ");
                eval("app.item"+num2str(j)+"_minus.Visible = 'off'; ");
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {207, '1x', 260};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Menu selected function: ShowerGel
        function ShowerGelSelected(app, event)
            app.menuSelected("沐浴露");
            
        end

        % Menu selected function: Shower
        function ShowerSelected(app, event)
            %add path once we select the menu
            addpath('src\');
            addpath('img\');
            
        end

        % Menu selected function: Shampoo
        function ShampooSelected(app, event)
            app.menuSelected("洗发露");
        end

        % Button pushed function: item1
        function item1ButtonPushed(app, event)
            app.itemButtonPushed(app.item1);
        end

        % Button pushed function: item2
        function item2ButtonPushed(app, event)
            app.itemButtonPushed(app.item2);
        end

        % Button pushed function: item1_plus
        function item1_plusButtonPushed(app, event)
            app.change_order_add(app.item1);
        end

        % Button pushed function: item2_plus
        function item2_plusButtonPushed(app, event)
            app.change_order_add(app.item2);
        end

        % Button pushed function: item1_minus
        function item1_minusButtonPushed(app, event)
            app.change_order_sub(app.item1);
        end

        % Button pushed function: item2_minus
        function item2_minusButtonPushed(app, event)
            app.change_order_sub(app.item2);
        end

        % Button pushed function: PayButton
        function PayButtonPushed(app, event)
            % create order and write it into database once we finish paying
            % all goods are in database, what we have to do is to find the
            % goods obj in the database
            app.cashier.create_order();
            for i = 1:app.item_nums
                name = app.item_name(i, 1);
                num = app.item_number(i, 1);
                goods = app.db.findGoods(name);
                if goods.brand == name
                    for j = 1:num
                        app.cashier.addItem(goods);
                    end
                end
            end
            app.cashier.writeOrderBackToDatabase();


            app.item_name = [];
            app.item_number = [];
            app.item_price = [];
            app.item_nums = 0;
            
            % change total price
            total_price = 0;
            app.TotalPrice.Value = total_price;
            
            tdata = table(app.item_name, app.item_number, app.item_price);
            app.UITable.Data = tdata;
            app.PaySuccess.Visible = 'on';
            
            
        end

        % Button pushed function: PaySuccess
        function PaySuccessButtonPushed(app, event)
            app.PaySuccess.Visible = 'off';
            
            
        end

        % Button pushed function: item3
        function item3ButtonPushed(app, event)
            app.itemButtonPushed(app.item3);
        end

        % Button pushed function: item4
        function item4ButtonPushed(app, event)
            app.itemButtonPushed(app.item4);
        end

        % Button pushed function: item5
        function item5ButtonPushed(app, event)
            app.itemButtonPushed(app.item5);
        end

        % Button pushed function: item6
        function item6ButtonPushed(app, event)
            app.itemButtonPushed(app.item6);
        end

        % Button pushed function: item7
        function item7ButtonPushed(app, event)
            app.itemButtonPushed(app.item7);
        end

        % Button pushed function: item8
        function item8ButtonPushed(app, event)
            app.itemButtonPushed(app.item8);
        end

        % Button pushed function: item9
        function item9ButtonPushed(app, event)
            app.itemButtonPushed(app.item9);
        end

        % Button pushed function: item10
        function item10ButtonPushed(app, event)
            app.itemButtonPushed(app.item10);
        end

        % Button pushed function: item3_plus
        function item3_plusButtonPushed(app, event)
            app.change_order_add(app.item3);
        end

        % Button pushed function: item3_minus
        function item3_minusButtonPushed(app, event)
            app.change_order_sub(app.item3);
        end

        % Button pushed function: item4_plus
        function item4_plusButtonPushed(app, event)
            app.change_order_add(app.item4);
        end

        % Button pushed function: item4_minus
        function item4_minusButtonPushed(app, event)
            app.change_order_sub(app.item4);
        end

        % Button pushed function: item5_plus
        function item5_plusButtonPushed(app, event)
            app.change_order_add(app.item5);
        end

        % Button pushed function: item5_minus
        function item5_minusButtonPushed(app, event)
            app.change_order_sub(app.item5);
        end

        % Button pushed function: item6_plus
        function item6_plusButtonPushed(app, event)
            app.change_order_add(app.item6);
        end

        % Button pushed function: item6_minus
        function item6_minusButtonPushed(app, event)
            app.change_order_sub(app.item6);
        end

        % Button pushed function: item7_plus
        function item7_plusButtonPushed(app, event)
            app.change_order_add(app.item7);
        end

        % Button pushed function: item7_minus
        function item7_minusButtonPushed(app, event)
            app.change_order_sub(app.item7);
        end

        % Button pushed function: item8_plus
        function item8_plusButtonPushed(app, event)
            app.change_order_add(app.item8);
        end

        % Button pushed function: item8_minus
        function item8_minusButtonPushed(app, event)
            app.change_order_sub(app.item8);
        end

        % Button pushed function: item9_plus
        function item9_plusButtonPushed(app, event)
            app.change_order_add(app.item9);
        end

        % Button pushed function: item9_minus
        function item9_minusButtonPushed(app, event)
            app.change_order_sub(app.item9);
        end

        % Button pushed function: item10_plus
        function item10_plusButtonPushed(app, event)
            app.change_order_add(app.item10);
        end

        % Button pushed function: item10_minus
        function item10_minusButtonPushed(app, event)
            app.change_order_sub(app.item10);
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            app.menuSelected("薯片");
        end

        % Menu selected function: Menu_8
        function Menu_8Selected(app, event)
            app.menuSelected("饼干");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Shower = uimenu(app.UIFigure);
            app.Shower.MenuSelectedFcn = createCallbackFcn(app, @ShowerSelected, true);
            app.Shower.Text = '美妆洗护';
            app.ShowerGel = uimenu(app.Shower);
            app.ShowerGel.MenuSelectedFcn = createCallbackFcn(app, @ShowerGelSelected, true);
            app.ShowerGel.Text = '沐浴露';
            app.Shampoo = uimenu(app.Shower);
            app.Shampoo.MenuSelectedFcn = createCallbackFcn(app, @ShampooSelected, true);
            app.Shampoo.Text = '洗发露';
            app.Menu = uimenu(app.Shower);
            app.Menu.Visible = 'off';
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.Shower);
            app.Menu2.Visible = 'off';
            app.Menu2.Text = 'Menu2';
            app.Menu3 = uimenu(app.Shower);
            app.Menu3.Visible = 'off';
            app.Menu3.Text = 'Menu3';
            app.Menu4 = uimenu(app.Shower);
            app.Menu4.Visible = 'off';
            app.Menu4.Text = 'Menu4';
            app.Menu5 = uimenu(app.Shower);
            app.Menu5.Visible = 'off';
            app.Menu5.Text = 'Menu5';
            app.Food = uimenu(app.UIFigure);
            app.Food.Text = '食品';
            app.Menu_2 = uimenu(app.Food);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '薯片';
            app.Menu_8 = uimenu(app.Food);
            app.Menu_8.MenuSelectedFcn = createCallbackFcn(app, @Menu_8Selected, true);
            app.Menu_8.Text = '饼干';
            app.Menu3_2 = uimenu(app.Food);
            app.Menu3_2.Visible = 'off';
            app.Menu3_2.Text = 'Menu3';
            app.Menu4_2 = uimenu(app.Food);
            app.Menu4_2.Visible = 'off';
            app.Menu4_2.Text = 'Menu4';
            app.Menu5_2 = uimenu(app.Food);
            app.Menu5_2.Visible = 'off';
            app.Menu5_2.Text = 'Menu5';
            app.Menu6 = uimenu(app.Food);
            app.Menu6.Visible = 'off';
            app.Menu6.Text = 'Menu6';
            app.Menu7 = uimenu(app.Food);
            app.Menu7.Visible = 'off';
            app.Menu7.Text = 'Menu7';
            app.Menu8 = uimenu(app.Food);
            app.Menu8.Visible = 'off';
            app.Menu8.Text = 'Menu8';
            app.Water = uimenu(app.UIFigure);
            app.Water.Text = '饮料';
            app.Menu_3 = uimenu(app.Water);
            app.Menu_3.Visible = 'off';
            app.Menu_3.Text = 'Menu';
            app.Menu2_3 = uimenu(app.Water);
            app.Menu2_3.Visible = 'off';
            app.Menu2_3.Text = 'Menu2';
            app.Menu3_3 = uimenu(app.Water);
            app.Menu3_3.Visible = 'off';
            app.Menu3_3.Text = 'Menu3';
            app.Menu4_3 = uimenu(app.Water);
            app.Menu4_3.Visible = 'off';
            app.Menu4_3.Text = 'Menu4';
            app.Menu5_3 = uimenu(app.Water);
            app.Menu5_3.Visible = 'off';
            app.Menu5_3.Text = 'Menu5';
            app.Menu6_2 = uimenu(app.Water);
            app.Menu6_2.Visible = 'off';
            app.Menu6_2.Text = 'Menu6';
            app.Menu7_2 = uimenu(app.Water);
            app.Menu7_2.Visible = 'off';
            app.Menu7_2.Text = 'Menu7';
            app.Menu8_2 = uimenu(app.Water);
            app.Menu8_2.Visible = 'off';
            app.Menu8_2.Text = 'Menu8';
            app.Drink = uimenu(app.UIFigure);
            app.Drink.Text = '中外名酒';
            app.Menu_4 = uimenu(app.Drink);
            app.Menu_4.Visible = 'off';
            app.Menu_4.Text = 'Menu';
            app.Menu2_4 = uimenu(app.Drink);
            app.Menu2_4.Visible = 'off';
            app.Menu2_4.Text = 'Menu2';
            app.Menu3_4 = uimenu(app.Drink);
            app.Menu3_4.Visible = 'off';
            app.Menu3_4.Text = 'Menu3';
            app.Menu4_4 = uimenu(app.Drink);
            app.Menu4_4.Visible = 'off';
            app.Menu4_4.Text = 'Menu4';
            app.Menu5_4 = uimenu(app.Drink);
            app.Menu5_4.Visible = 'off';
            app.Menu5_4.Text = 'Menu5';
            app.Menu6_3 = uimenu(app.Drink);
            app.Menu6_3.Visible = 'off';
            app.Menu6_3.Text = 'Menu6';
            app.Menu7_3 = uimenu(app.Drink);
            app.Menu7_3.Visible = 'off';
            app.Menu7_3.Text = 'Menu7';
            app.Menu8_3 = uimenu(app.Drink);
            app.Menu8_3.Visible = 'off';
            app.Menu8_3.Text = 'Menu8';
            app.Tools = uimenu(app.UIFigure);
            app.Tools.Text = '文具体育';
            app.Menu_5 = uimenu(app.Tools);
            app.Menu_5.Visible = 'off';
            app.Menu_5.Text = 'Menu';
            app.Menu2_5 = uimenu(app.Tools);
            app.Menu2_5.Visible = 'off';
            app.Menu2_5.Text = 'Menu2';
            app.Menu3_5 = uimenu(app.Tools);
            app.Menu3_5.Visible = 'off';
            app.Menu3_5.Text = 'Menu3';
            app.Menu4_5 = uimenu(app.Tools);
            app.Menu4_5.Visible = 'off';
            app.Menu4_5.Text = 'Menu4';
            app.Menu5_5 = uimenu(app.Tools);
            app.Menu5_5.Visible = 'off';
            app.Menu5_5.Text = 'Menu5';
            app.Menu6_4 = uimenu(app.Tools);
            app.Menu6_4.Visible = 'off';
            app.Menu6_4.Text = 'Menu6';
            app.Menu7_4 = uimenu(app.Tools);
            app.Menu7_4.Visible = 'off';
            app.Menu7_4.Text = 'Menu7';
            app.Menu8_4 = uimenu(app.Tools);
            app.Menu8_4.Visible = 'off';
            app.Menu8_4.Text = 'Menu8';
            app.Cloth = uimenu(app.UIFigure);
            app.Cloth.Text = '家纺内衣';
            app.Menu_6 = uimenu(app.Cloth);
            app.Menu_6.Visible = 'off';
            app.Menu_6.Text = 'Menu';
            app.Menu2_6 = uimenu(app.Cloth);
            app.Menu2_6.Visible = 'off';
            app.Menu2_6.Text = 'Menu2';
            app.Menu3_6 = uimenu(app.Cloth);
            app.Menu3_6.Visible = 'off';
            app.Menu3_6.Text = 'Menu3';
            app.Menu4_6 = uimenu(app.Cloth);
            app.Menu4_6.Visible = 'off';
            app.Menu4_6.Text = 'Menu4';
            app.Menu5_6 = uimenu(app.Cloth);
            app.Menu5_6.Visible = 'off';
            app.Menu5_6.Text = 'Menu5';
            app.Menu6_5 = uimenu(app.Cloth);
            app.Menu6_5.Visible = 'off';
            app.Menu6_5.Text = 'Menu6';
            app.Menu7_5 = uimenu(app.Cloth);
            app.Menu7_5.Visible = 'off';
            app.Menu7_5.Text = 'Menu7';
            app.Menu8_5 = uimenu(app.Cloth);
            app.Menu8_5.Visible = 'off';
            app.Menu8_5.Text = 'Menu8';
            app.Digits = uimenu(app.UIFigure);
            app.Digits.Text = '电器数码';
            app.Menu_7 = uimenu(app.Digits);
            app.Menu_7.Visible = 'off';
            app.Menu_7.Text = 'Menu';
            app.Menu2_7 = uimenu(app.Digits);
            app.Menu2_7.Visible = 'off';
            app.Menu2_7.Text = 'Menu2';
            app.Menu3_7 = uimenu(app.Digits);
            app.Menu3_7.Visible = 'off';
            app.Menu3_7.Text = 'Menu3';
            app.Menu4_7 = uimenu(app.Digits);
            app.Menu4_7.Visible = 'off';
            app.Menu4_7.Text = 'Menu4';
            app.Menu5_7 = uimenu(app.Digits);
            app.Menu5_7.Visible = 'off';
            app.Menu5_7.Text = 'Menu5';
            app.Menu6_6 = uimenu(app.Digits);
            app.Menu6_6.Visible = 'off';
            app.Menu6_6.Text = 'Menu6';
            app.Menu7_6 = uimenu(app.Digits);
            app.Menu7_6.Visible = 'off';
            app.Menu7_6.Text = 'Menu7';
            app.Menu8_6 = uimenu(app.Digits);
            app.Menu8_6.Visible = 'off';
            app.Menu8_6.Text = 'Menu8';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.item1 = uibutton(app.LeftPanel, 'push');
            app.item1.ButtonPushedFcn = createCallbackFcn(app, @item1ButtonPushed, true);
            app.item1.Visible = 'off';
            app.item1.Text = '';
            app.item2 = uibutton(app.LeftPanel, 'push');
            app.item2.ButtonPushedFcn = createCallbackFcn(app, @item2ButtonPushed, true);
            app.item2.Visible = 'off';
            app.item2.Text = '';
            app.item1_plus = uibutton(app.LeftPanel, 'push');
            app.item1_plus.ButtonPushedFcn = createCallbackFcn(app, @item1_plusButtonPushed, true);
            app.item1_plus.Visible = 'off';
            app.item1_plus.Text = {'+'; ''};
            app.item2_plus = uibutton(app.LeftPanel, 'push');
            app.item2_plus.ButtonPushedFcn = createCallbackFcn(app, @item2_plusButtonPushed, true);
            app.item2_plus.Visible = 'off';
            app.item2_plus.Text = {'+'; ''};
            app.item1_minus = uibutton(app.LeftPanel, 'push');
            app.item1_minus.ButtonPushedFcn = createCallbackFcn(app, @item1_minusButtonPushed, true);
            app.item1_minus.Visible = 'off';
            app.item1_minus.Text = '-';
            app.item2_minus = uibutton(app.LeftPanel, 'push');
            app.item2_minus.ButtonPushedFcn = createCallbackFcn(app, @item2_minusButtonPushed, true);
            app.item2_minus.Visible = 'off';
            app.item2_minus.Text = '-';
            app.item3 = uibutton(app.LeftPanel, 'push');
            app.item3.ButtonPushedFcn = createCallbackFcn(app, @item3ButtonPushed, true);
            app.item3.Visible = 'off';
            app.item3.Text = '';
            app.item4 = uibutton(app.LeftPanel, 'push');
            app.item4.ButtonPushedFcn = createCallbackFcn(app, @item4ButtonPushed, true);
            app.item4.Visible = 'off';
            app.item4.Text = '';
            app.item5 = uibutton(app.LeftPanel, 'push');
            app.item5.ButtonPushedFcn = createCallbackFcn(app, @item5ButtonPushed, true);
            app.item5.Visible = 'off';
            app.item5.Text = '';
            app.item6 = uibutton(app.LeftPanel, 'push');
            app.item6.ButtonPushedFcn = createCallbackFcn(app, @item6ButtonPushed, true);
            app.item6.Visible = 'off';
            app.item6.Text = '';
            app.item7 = uibutton(app.LeftPanel, 'push');
            app.item7.ButtonPushedFcn = createCallbackFcn(app, @item7ButtonPushed, true);
            app.item7.Visible = 'off';
            app.item7.Text = '';
            app.item8 = uibutton(app.LeftPanel, 'push');
            app.item8.ButtonPushedFcn = createCallbackFcn(app, @item8ButtonPushed, true);
            app.item8.Visible = 'off';
            app.item8.Text = '';
            app.item9 = uibutton(app.LeftPanel, 'push');
            app.item9.ButtonPushedFcn = createCallbackFcn(app, @item9ButtonPushed, true);
            app.item9.Visible = 'off';
            app.item9.Text = '';
            app.item10 = uibutton(app.LeftPanel, 'push');
            app.item10.ButtonPushedFcn = createCallbackFcn(app, @item10ButtonPushed, true);
            app.item10.Visible = 'off';
            app.item10.Text = '';
            app.item3_plus = uibutton(app.LeftPanel, 'push');
            app.item3_plus.ButtonPushedFcn = createCallbackFcn(app, @item3_plusButtonPushed, true);
            app.item3_plus.Visible = 'off';
            app.item3_plus.Text = {'+'; ''};
            app.item3_minus = uibutton(app.LeftPanel, 'push');
            app.item3_minus.ButtonPushedFcn = createCallbackFcn(app, @item3_minusButtonPushed, true);
            app.item3_minus.Visible = 'off';
            app.item3_minus.Text = '-';
            app.item4_plus = uibutton(app.LeftPanel, 'push');
            app.item4_plus.ButtonPushedFcn = createCallbackFcn(app, @item4_plusButtonPushed, true);
            app.item4_plus.Visible = 'off';
            app.item4_plus.Text = {'+'; ''};
            app.item4_minus = uibutton(app.LeftPanel, 'push');
            app.item4_minus.ButtonPushedFcn = createCallbackFcn(app, @item4_minusButtonPushed, true);
            app.item4_minus.Visible = 'off';
            app.item4_minus.Text = '-';
            app.item5_plus = uibutton(app.LeftPanel, 'push');
            app.item5_plus.ButtonPushedFcn = createCallbackFcn(app, @item5_plusButtonPushed, true);
            app.item5_plus.Visible = 'off';
            app.item5_plus.Text = {'+'; ''};
            app.item5_minus = uibutton(app.LeftPanel, 'push');
            app.item5_minus.ButtonPushedFcn = createCallbackFcn(app, @item5_minusButtonPushed, true);
            app.item5_minus.Visible = 'off';
            app.item5_minus.Text = '-';
            app.item6_plus = uibutton(app.LeftPanel, 'push');
            app.item6_plus.ButtonPushedFcn = createCallbackFcn(app, @item6_plusButtonPushed, true);
            app.item6_plus.Visible = 'off';
            app.item6_plus.Text = {'+'; ''};
            app.item6_minus = uibutton(app.LeftPanel, 'push');
            app.item6_minus.ButtonPushedFcn = createCallbackFcn(app, @item6_minusButtonPushed, true);
            app.item6_minus.Visible = 'off';
            app.item6_minus.Text = '-';
            app.item7_plus = uibutton(app.LeftPanel, 'push');
            app.item7_plus.ButtonPushedFcn = createCallbackFcn(app, @item7_plusButtonPushed, true);
            app.item7_plus.Visible = 'off';
            app.item7_plus.Text = {'+'; ''};
            app.item7_minus = uibutton(app.LeftPanel, 'push');
            app.item7_minus.ButtonPushedFcn = createCallbackFcn(app, @item7_minusButtonPushed, true);
            app.item7_minus.Visible = 'off';
            app.item7_minus.Text = '-';
            app.item8_plus = uibutton(app.LeftPanel, 'push');
            app.item8_plus.ButtonPushedFcn = createCallbackFcn(app, @item8_plusButtonPushed, true);
            app.item8_plus.Visible = 'off';
            app.item8_plus.Text = {'+'; ''};
            app.item8_minus = uibutton(app.LeftPanel, 'push');
            app.item8_minus.ButtonPushedFcn = createCallbackFcn(app, @item8_minusButtonPushed, true);
            app.item8_minus.Visible = 'off';
            app.item8_minus.Text = '-';
            app.item9_plus = uibutton(app.LeftPanel, 'push');
            app.item9_plus.ButtonPushedFcn = createCallbackFcn(app, @item9_plusButtonPushed, true);
            app.item9_plus.Visible = 'off';
            app.item9_plus.Text = {'+'; ''};
            app.item9_minus = uibutton(app.LeftPanel, 'push');
            app.item9_minus.ButtonPushedFcn = createCallbackFcn(app, @item9_minusButtonPushed, true);
            app.item9_minus.Visible = 'off';
            app.item9_minus.Text = '-';
            app.item10_plus = uibutton(app.LeftPanel, 'push');
            app.item10_plus.ButtonPushedFcn = createCallbackFcn(app, @item10_plusButtonPushed, true);
            app.item10_plus.Visible = 'off';
            app.item10_plus.Text = {'+'; ''};
            app.item10_minus = uibutton(app.LeftPanel, 'push');
            app.item10_minus.ButtonPushedFcn = createCallbackFcn(app, @item10_minusButtonPushed, true);
            app.item10_minus.Visible = 'off';
            app.item10_minus.Text = '-';
            app.CenterPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.CenterPanel);
            app.Image.Visible = 'off';
            app.Label = uilabel(app.CenterPanel);
            app.Label.Visible = 'off';
            app.Label.Text = '单价：';
            app.unitPrice = uieditfield(app.CenterPanel, 'numeric');
            app.unitPrice.Limits = [0 Inf];
            app.unitPrice.Editable = 'off';
            app.unitPrice.Visible = 'off';
            app.unitPrice.Value = 1;
            app.PaySuccess = uibutton(app.CenterPanel, 'push');
            app.PaySuccess.ButtonPushedFcn = createCallbackFcn(app, @PaySuccessButtonPushed, true);
            app.PaySuccess.Visible = 'off';
            app.PaySuccess.Text = '支付成功！点击继续下次购物';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'商品'; '数量'; '价钱'};
            app.UITable.RowName = {''};
            app.UITable.ColumnSortable = false;
            app.UITable.ColumnEditable = true;
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '总价：';
            app.TotalPrice = uieditfield(app.RightPanel, 'numeric');
            app.TotalPrice.Limits = [0 Inf];
            app.TotalPrice.Editable = 'off';
            app.PayButton = uibutton(app.RightPanel, 'push');
            app.PayButton.ButtonPushedFcn = createCallbackFcn(app, @PayButtonPushed, true);
            app.PayButton.Text = '支付';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CustomerUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end