classdef ManagerUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        Label           matlab.ui.control.Label
        price           matlab.ui.control.NumericEditField
        TextArea        matlab.ui.control.TextArea
        Label_2         matlab.ui.control.Label
        type            matlab.ui.control.EditField
        Label_3         matlab.ui.control.Label
        brand           matlab.ui.control.EditField
        Label_4         matlab.ui.control.Label
        img             matlab.ui.control.EditField
        errorInfo       matlab.ui.control.Button
        RightPanel      matlab.ui.container.Panel
        registerButton  matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        manager = Manager()  % manager class, used for adding goods
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: registerButton
        function registerButtonPushed(app, event)
            if exist(app.brand.Value, app.type.Value) && exist(app.price.Value, app.img.Value)
                % check if the img src is correct
                if exist(app.img.Value, 'file')
                    app.manager.registerGoods(app.brand.Value, app.type.Value, app.price.Value, app.img.Value)
                end
            else
                app.errorInfo.Visible = 'on';
                app.registerButton.Visible = 'off';
            end
            
        end

        % Button pushed function: errorInfo
        function errorInfoButtonPushed(app, event)
            app.errorInfo.Visible = 'off';
            app.registerButton.Visible = 'on';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {449, 449};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {429, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '商品价格：';
            app.price = uieditfield(app.LeftPanel, 'numeric');
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Value = {'您可输入以下信息来登记新的商品：'};
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '商品种类：';
            app.type = uieditfield(app.LeftPanel, 'text');
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '商品品牌名：';
            app.brand = uieditfield(app.LeftPanel, 'text');
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '(额外) 商品图片：';
            app.img = uieditfield(app.LeftPanel, 'text');
            app.errorInfo = uibutton(app.LeftPanel, 'push');
            app.errorInfo.ButtonPushedFcn = createCallbackFcn(app, @errorInfoButtonPushed, true);
            app.errorInfo.Visible = 'off';
            app.errorInfo.Text = '请确保输入了正确的商品信息！！';
            app.RightPanel = uipanel(app.GridLayout);
            app.registerButton = uibutton(app.RightPanel, 'push');
            app.registerButton.ButtonPushedFcn = createCallbackFcn(app, @registerButtonPushed, true);
            app.registerButton.Text = '登记';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManagerUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end