classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Menu           matlab.ui.container.Menu
        Menu_2         matlab.ui.container.Menu
        Menu2_2        matlab.ui.container.Menu
        Menu3          matlab.ui.container.Menu
        Menu4          matlab.ui.container.Menu
        Menu5          matlab.ui.container.Menu
        Menu6          matlab.ui.container.Menu
        Menu_3         matlab.ui.container.Menu
        Menu2          matlab.ui.container.Menu
        GridLayout     matlab.ui.container.GridLayout
        LeftPanel      matlab.ui.container.Panel
        ListBoxLabel   matlab.ui.control.Label
        ListBox        matlab.ui.control.ListBox
        DropDownLabel  matlab.ui.control.Label
        DropDown       matlab.ui.control.DropDown
        RightPanel     matlab.ui.container.Panel
        TabGroup       matlab.ui.container.TabGroup
        Tab            matlab.ui.container.Tab
        Tab2           matlab.ui.container.Tab
        Tab3           matlab.ui.container.Tab
        Button         matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Image       matlab.ui.control.Image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function: Button, Menu2_2, Tab2
        function Menu_2Selected(app, event)
            % Create Image
            app.Image = uiimage(app.RightPanel);
            app.Image.Position = [16 99 350 330];
            app.Image.ImageSource = 'cristiano_google_goals.jpg';

            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            % Create Image
            app.Image = uiimage(app.RightPanel);
            app.Image.Position = [36 151 293 211];
            app.Image.ImageSource = 'cristiano_google_goals.jpg';

            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end

        % Size changed function: Tab
        function TabSizeChanged(app, event)
            position = app.Tab.Position;
            value = app.ListBox.Value;
            % Create Image
            app.Image = uiimage(app.RightPanel);
            app.Image.Position = [36 151 293 211];
            app.Image.ImageSource = 'cristiano_google_goals.jpg';

            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.Menu);
            app.Menu2_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu2_2.Text = 'Menu2';
            app.Menu3 = uimenu(app.Menu);
            app.Menu3.Text = 'Menu3';
            app.Menu4 = uimenu(app.Menu);
            app.Menu4.Text = 'Menu4';
            app.Menu5 = uimenu(app.Menu);
            app.Menu5.Text = 'Menu5';
            app.Menu6 = uimenu(app.Menu);
            app.Menu6.Text = 'Menu6';
            app.Menu_3 = uimenu(app.Menu6);
            app.Menu_3.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ListBoxLabel = uilabel(app.LeftPanel);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.LeftPanel);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.DropDownLabel = uilabel(app.LeftPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab = uitab(app.TabGroup);
            app.Tab.SizeChangedFcn = createCallbackFcn(app, @TabSizeChanged, true);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.SizeChangedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Tab2.Title = 'Tab2';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end